classdef app_under_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlotButton            matlab.ui.control.Button
        FrequencySlider       matlab.ui.control.Slider
        FrequencySliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        AmplitudeSliderLabel  matlab.ui.control.Label
        TimeSlider            matlab.ui.control.Slider
        TimeSliderLabel       matlab.ui.control.Label
        PlotButtonGroup       matlab.ui.container.ButtonGroup
        SpectogramButton      matlab.ui.control.RadioButton
        FrequencyButton       matlab.ui.control.RadioButton
        TimeButton            matlab.ui.control.RadioButton
        GenerateButtonGroup   matlab.ui.container.ButtonGroup
        SincButton            matlab.ui.control.RadioButton
        ChirpButton           matlab.ui.control.RadioButton
        TriangleButton        matlab.ui.control.RadioButton
        SquareButton          matlab.ui.control.RadioButton
        SineButton            matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x % signal
        t % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            title(app.UIAxes, num2str(app.FrequencySlider.Value));
        end

        % Callback function: not associated with a component
        function GenerateButtonGroupSelectionChanged(app, event)
            selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            %title(app.UIAxes, selectedButton);
            A = app.AmplitudeSlider.Value;
            T = app.TimeSlider.Value;
            f = app.FrequencySlider.Value;
            fs = 8000;
            app.t = 0:1/fs:T-1/fs;
            switch selectedButton
                case 'Sine'
                    app.x = A*sin(2*pi*f*app.t);
                    %plot(app.UIAxes, app.t, app.x);
                    
                otherwise
                    
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot(app.UIAxes, app.t, app.x);
        end

        % Callback function: not associated with a component
        function GenerateButtonGroupButtonDown(app, event)
            selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            %title(app.UIAxes, selectedButton);
            A = app.AmplitudeSlider.Value;
            T = app.TimeSlider.Value;
            f = app.FrequencySlider.Value;
            fs = 8000;
            app.t = 0:1/fs:T-1/fs;
            switch selectedButton
                case 'Sine'
                    app.x = A*sin(2*pi*f*app.t);
                    %plot(app.UIAxes, app.t, app.x);
                    
                otherwise
                    
            end
        end

        % Selection changed function: GenerateButtonGroup
        function GenerateButtonGroupSelectionChanged2(app, event)
         
            selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            %title(app.UIAxes, selectedButton);
            A = app.AmplitudeSlider.Value;
            T = app.TimeSlider.Value;
            f = app.FrequencySlider.Value;
            fs = 8000;
            app.t = 0:1/fs:T-1/fs;
            switch selectedButton
                case 'Sine'
                    app.x = A*sin(2*pi*f*app.t);
                    %plot(app.UIAxes, app.t, app.x);
                    
                otherwise
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GenerateButtonGroup = uibuttongroup(app.UIFigure);
            app.GenerateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenerateButtonGroupSelectionChanged2, true);
            app.GenerateButtonGroup.Title = 'Generate';
            app.SineButton = uiradiobutton(app.GenerateButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.SquareButton = uiradiobutton(app.GenerateButtonGroup);
            app.SquareButton.Text = 'Square';
            app.TriangleButton = uiradiobutton(app.GenerateButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.ChirpButton = uiradiobutton(app.GenerateButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.SincButton = uiradiobutton(app.GenerateButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.Title = 'Plot';
            app.TimeButton = uiradiobutton(app.PlotButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.PlotButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.SpectogramButton = uiradiobutton(app.PlotButtonGroup);
            app.SpectogramButton.Text = 'Spectogram';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0.1 10];
            app.TimeSlider.MajorTicks = [0 2 4 6 8 10];
            app.TimeSlider.Step = 0.1;
            app.TimeSlider.Value = 1;
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [0.1 5];
            app.AmplitudeSlider.MajorTicks = [0 1 2 3 4 5];
            app.AmplitudeSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.AmplitudeSlider.Step = 0.1;
            app.AmplitudeSlider.Value = 1;
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [1 3999];
            app.FrequencySlider.MajorTicks = [0 1000 2000 3000 4000];
            app.FrequencySlider.MajorTickLabels = {'0', '1000', '2000', '3000', '4000', '5000'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.Step = 0.1;
            app.FrequencySlider.Value = 100;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_under_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end