classdef app_under_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SignalGeneratorTab_2           matlab.ui.container.Tab
        GenerateButtonGroup            matlab.ui.container.ButtonGroup
        SincButton                     matlab.ui.control.RadioButton
        ChirpButton                    matlab.ui.control.RadioButton
        TriangleButton                 matlab.ui.control.RadioButton
        SquareButton                   matlab.ui.control.RadioButton
        SineButton                     matlab.ui.control.RadioButton
        PlaySoundSwitch                matlab.ui.control.Switch
        PlaySoundSwitchLabel           matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        DutyCycleEditField             matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        AmplitudeSliderLabel           matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        InputAudioAnalyzerTab          matlab.ui.container.Tab
        FIRFilterPanel                 matlab.ui.container.Panel
        WindowHammingLabel             matlab.ui.control.Label
        FilterOrder50Label             matlab.ui.control.Label
        PlayFilteredAudioButton        matlab.ui.control.Button
        AnalyzeButton                  matlab.ui.control.Button
        ApplyFIRFilterCheckBox         matlab.ui.control.CheckBox
        CutoffFrequencyEditField       matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        RecordingPanel                 matlab.ui.container.Panel
        InputDeviceIDDropDown          matlab.ui.control.DropDown
        InputDeviceIDDropDownLabel     matlab.ui.control.Label
        Samplingfrequency441kHzLabel   matlab.ui.control.Label
        ReadyLabel                     matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        RecordButton                   matlab.ui.control.Button
        RecordingTimeEditField         matlab.ui.control.NumericEditField
        RecordingTimeEditFieldLabel    matlab.ui.control.Label
        PlotButtonGroup                matlab.ui.container.ButtonGroup
        SpectogramButton               matlab.ui.control.RadioButton
        FrequencyButton                matlab.ui.control.RadioButton
        TimeButton                     matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    x                  % Generated signal
    t                  % Time axis

    micSignal          % Original recorded audio
    filteredSignal     % FIR filtered audio

    Fs                 % Sampling frequency

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Fs = 44100;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function FrequencySliderValueChanged(app, event)

        end

        % Callback function
        function GenerateButtonGroupSelectionChanged(app, event)

        end

        % Callback function: GenerateButtonGroup, PlotButton
        function PlotButtonPushed(app, event)

        selectedPlot = app.PlotButtonGroup.SelectedObject.Text;

        fs = 8000;
        A = app.AmplitudeSlider.Value;

        T = app.TimeSlider.Value;

        f = app.FrequencyEditField.Value;

    app.t = 0:1/fs:T-1/fs;
selectedButton = app.GenerateButtonGroup.SelectedObject.Text;

            % Generate signal
            switch selectedButton

                case 'Sine'

                    app.x = A*sin(2*pi*f*app.t);

                case 'Square'
                    D = app.DutyCycleEditField.Value;
                    app.x = A*square(2*pi*f*app.t, D);

                case 'Triangle'
                    D = app.DutyCycleEditField.Value;
                    app.x = A*sawtooth(2*pi*f*app.t, 0.5);

                case 'Chirp'

                    app.x = A*chirp(app.t, f, app.t(end), 2*f);

                case 'Sinc'

                    app.x = A*sinc(2*f*(app.t - app.t(end)/2));

            end 
    switch selectedPlot

        case 'Time'

            plot(app.UIAxes, app.t, app.x);

            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time Domain');

        case 'Frequency'

            N = length(app.x);

            X = abs(fft(app.x));

            freq = (0:N-1)*(fs/N);

            plot(app.UIAxes, freq, X);

            xlim(app.UIAxes, [0 500]);

            xlabel(app.UIAxes, 'Frequency');
            ylabel(app.UIAxes, 'Magnitude');
            title(app.UIAxes, 'Frequency Spectrum');

        case 'Spectogram'

            figure;

            spectrogram(app.x, 128, 120, 128, fs, 'yaxis');

            title('Spectrogram');
    end
    zoom(app.UIFigure, 'on');
    if strcmp(app.PlaySoundSwitch.Value, "On")

                clear sound

sound(app.x, fs);

            end

        end

        % Callback function
        function GenerateButtonGroupButtonDown(app, event)
 
        end

        % Callback function
        function GenerateButtonGroupSelectionChanged2(app, event)
         
            selectedButton = app.GenerateButtonGroup.SelectedObject.Text;
            A = app.AmplitudeSlider.Value;
            T = app.TimeSlider.Value;
            f = app.FrequencyEditField.Value;
            fs = 8000;
            app.t = 0:1/fs:T-1/fs;
            switch selectedButton

                case 'Sine'
                    app.x = A*sin(2*pi*f*app.t);
            
                case 'Square'
                    app.x = A*square(2*pi*f*app.t);
            
                case 'Triangle'
                    app.x = A*sawtooth(2*pi*f*app.t, 0.5);
            
                case 'Chirp'
                    app.x = A*chirp(app.t, f, app.t(end), 2*f);
            
                case 'Sinc'
                    app.x = A*sinc(2*f*(app.t - app.t(end)/2));

end
        end

        % Callback function: not associated with a component
        function SamplingRateDropDownValueChanged(app, event)
            value = app.SamplingRateDropDown.Value;
            app.Fs = str2double(app.SamplingRateDropDown.Value);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            duration = app.RecordingTimeEditField.Value;

            app.ReadyLabel.Text = 'Recording...';
            drawnow;
            
            deviceID = str2double(app.InputDeviceIDDropDown.Value);

            recObj = audiorecorder(app.Fs,16,1,deviceID);
            
            recordblocking(recObj,duration);
            
            app.micSignal = getaudiodata(recObj);
            app.filteredSignal = [];
            app.PlotButtonGroupSelectionChanged([]);
            disp(length(app.micSignal))
            
            app.ReadyLabel.Text = 'Done';
            selectedPlot = app.PlotButtonGroup.SelectedObject.Text;
            switch selectedPlot

                case 'Time'

                    t = (0:length(app.micSignal)-1)/app.Fs;
            
                    plot(app.UIAxes,t,app.micSignal);
            
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'Amplitude');
                    title(app.UIAxes,'Recorded Signal');

                case 'Frequency'

                    N = length(app.micSignal);
                
                    X = abs(fft(app.micSignal));
                
                    freq = (0:N-1)*(app.Fs/N);
                
                    plot(app.UIAxes,freq,X);
                
                    xlim(app.UIAxes,[0 5000]);
                
                    xlabel(app.UIAxes,'Frequency (Hz)');
                    ylabel(app.UIAxes,'Magnitude');
                    title(app.UIAxes,'Frequency Spectrum');

            end
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotButtonGroup.SelectedObject;
            if isempty(app.filteredSignal)
            
                if isempty(app.micSignal)
                    return;
                end
            
            end
            if ~isempty(app.filteredSignal)
                signalToPlot = app.filteredSignal;
            else
                signalToPlot = app.micSignal;
            end
            selectedPlot = app.PlotButtonGroup.SelectedObject.Text;
        
            switch selectedPlot

                case 'Time'
            
                    t = (0:length(signalToPlot)-1)/app.Fs;
                    cla(app.UIAxes);
                    plot(app.UIAxes,t,signalToPlot);
            
                    xlim(app.UIAxes,'auto');
                    ylim(app.UIAxes,'auto');

                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'Amplitude');
                    title(app.UIAxes,'Recorded Signal');

                case 'Frequency'

                    N = length(signalToPlot);

                    X = abs(fft(signalToPlot));
                
                    freq = (0:N-1)*(app.Fs/N);
                
                    plot(app.UIAxes,freq,X);
                
                    xlim(app.UIAxes,[0 5000]);
                
                    xlabel(app.UIAxes,'Frequency (Hz)');
                    ylabel(app.UIAxes,'Magnitude');
                    title(app.UIAxes,'Frequency Spectrum');
                case 'Spectogram'

                    spectrogram(signalToPlot,...
                                128,...
                                120,...
                                128,...
                                app.Fs,...
                                'yaxis');
            end
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if isempty(app.micSignal)

                uialert(app.UIFigure,...
                        'Please record audio first.',...
                        'No Audio');
            
                return;
            
            end
            app.ReadyLabel.Text = 'Analyzing...';
            drawnow;
            fc = app.CutoffFrequencyEditField.Value;
            order = 50;
            Wn = fc/(app.Fs/2);
            window = hamming(order + 1);
            b = fir1(order, Wn, 'low', window);
            if app.ApplyFIRFilterCheckBox.Value

                app.filteredSignal = filtfilt(b, 1, app.micSignal);
            
            else
            
                app.filteredSignal = app.micSignal;
            
            end
            % selectedPlot = app.PlotButtonGroup.SelectedObject.Text;
            % switch selectedPlot
            % 
            % case 'Time'
            % 
            %     t = (0:length(app.filteredSignal)-1)/app.Fs;
            % 
            %     plot(app.UIAxes,t,app.filteredSignal);
            % 
            %     xlim(app.UIAxes,'auto');
            %     ylim(app.UIAxes,'auto');
            % 
            %     xlabel(app.UIAxes,'Time (s)');
            %     ylabel(app.UIAxes,'Amplitude');
            %     title(app.UIAxes,'Filtered Signal');
            % 
            % case 'Frequency'
            % 
            %     N = length(app.filteredSignal);
            % 
            %     X = abs(fft(app.filteredSignal));
            % 
            %     freq = (0:N-1)*(app.Fs/N);
            % 
            %     plot(app.UIAxes,freq,X);
            % 
            %     xlim(app.UIAxes,[0 5000]);
            % 
            %     xlabel(app.UIAxes,'Frequency (Hz)');
            %     ylabel(app.UIAxes,'Magnitude');
            %     title(app.UIAxes,'Filtered Frequency Spectrum');
            % 
            %  case 'Spectogram'
            % 
            %     spectrogram(app.filteredSignal,...
            %                 128,...
            %                 120,...
            %                 128,...
            %                 app.Fs,...
            %                 'yaxis');
            % 
            %     title('Filtered Spectrogram');
            % 
            % end
            
app.ReadyLabel.Text = 'Analysis Done';
app.PlotButtonGroupSelectionChanged([]);
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            if isempty(app.filteredSignal)

                uialert(app.UIFigure,...
                        'Please analyze the audio first.',...
                        'No Filtered Audio');
            
                return;
            
            end

        soundsc(app.filteredSignal, app.Fs);
        end

        % Callback function: not associated with a component
        function GenerateButtonGroupSelectionChanged3(app, event)
            selectedButton = app.GenerateButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.PlotButtonGroup.Title = 'Plot';
            app.TimeButton = uiradiobutton(app.PlotButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Interpreter = 'latex';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.PlotButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.FrequencyButton.Interpreter = 'latex';
            app.SpectogramButton = uiradiobutton(app.PlotButtonGroup);
            app.SpectogramButton.Text = 'Spectogram';
            app.SpectogramButton.Interpreter = 'latex';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalGeneratorTab_2 = uitab(app.TabGroup);
            app.SignalGeneratorTab_2.Title = 'Signal Generator';
            app.TimeSliderLabel = uilabel(app.SignalGeneratorTab_2);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.SignalGeneratorTab_2);
            app.TimeSlider.Limits = [0.1 10];
            app.TimeSlider.MajorTicks = [0 2 4 6 8 10];
            app.TimeSlider.Step = 0.1;
            app.TimeSlider.Value = 1;
            app.AmplitudeSliderLabel = uilabel(app.SignalGeneratorTab_2);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.SignalGeneratorTab_2);
            app.AmplitudeSlider.Limits = [0.1 5];
            app.AmplitudeSlider.MajorTicks = [0 1 2 3 4 5];
            app.AmplitudeSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.AmplitudeSlider.Step = 0.1;
            app.AmplitudeSlider.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.SignalGeneratorTab_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SignalGeneratorTab_2, 'numeric');
            app.FrequencyEditField.Limits = [1 3999];
            app.FrequencyEditField.Value = 100;
            app.DutyCycleEditFieldLabel = uilabel(app.SignalGeneratorTab_2);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.SignalGeneratorTab_2, 'numeric');
            app.DutyCycleEditField.Limits = [0 100];
            app.DutyCycleEditField.Value = 5;
            app.PlotButton = uibutton(app.SignalGeneratorTab_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlaySoundSwitchLabel = uilabel(app.SignalGeneratorTab_2);
            app.PlaySoundSwitchLabel.Text = 'Play Sound';
            app.PlaySoundSwitch = uiswitch(app.SignalGeneratorTab_2, 'slider');
            app.GenerateButtonGroup = uibuttongroup(app.SignalGeneratorTab_2);
            app.GenerateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.GenerateButtonGroup.Title = 'Generate';
            app.SineButton = uiradiobutton(app.GenerateButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Interpreter = 'latex';
            app.SquareButton = uiradiobutton(app.GenerateButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Interpreter = 'latex';
            app.TriangleButton = uiradiobutton(app.GenerateButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.TriangleButton.Interpreter = 'latex';
            app.TriangleButton.Value = true;
            app.ChirpButton = uiradiobutton(app.GenerateButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.ChirpButton.Interpreter = 'latex';
            app.SincButton = uiradiobutton(app.GenerateButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.SincButton.Interpreter = 'latex';
            app.InputAudioAnalyzerTab = uitab(app.TabGroup);
            app.InputAudioAnalyzerTab.Title = 'Input Audio Analyzer';
            app.RecordingPanel = uipanel(app.InputAudioAnalyzerTab);
            app.RecordingPanel.Title = 'Recording Panel';
            app.RecordingTimeEditFieldLabel = uilabel(app.RecordingPanel);
            app.RecordingTimeEditFieldLabel.Text = 'Recording Time';
            app.RecordingTimeEditField = uieditfield(app.RecordingPanel, 'numeric');
            app.RecordingTimeEditField.Limits = [1 30];
            app.RecordingTimeEditField.Value = 5;
            app.RecordButton = uibutton(app.RecordingPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StatusLabel = uilabel(app.RecordingPanel);
            app.StatusLabel.Text = 'Status:';
            app.ReadyLabel = uilabel(app.RecordingPanel);
            app.ReadyLabel.Text = 'Ready';
            app.Samplingfrequency441kHzLabel = uilabel(app.RecordingPanel);
            app.Samplingfrequency441kHzLabel.Text = 'Sampling frequency: 44.1kHz';
            app.InputDeviceIDDropDownLabel = uilabel(app.RecordingPanel);
            app.InputDeviceIDDropDownLabel.Text = 'Input Device ID';
            app.InputDeviceIDDropDown = uidropdown(app.RecordingPanel);
            app.InputDeviceIDDropDown.Items = {'0', '1', '2', '3', '4'};
            app.InputDeviceIDDropDown.Value = '0';
            app.FIRFilterPanel = uipanel(app.InputAudioAnalyzerTab);
            app.FIRFilterPanel.Title = 'FIR Filter';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.FIRFilterPanel);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.FIRFilterPanel, 'numeric');
            app.CutoffFrequencyEditField.Value = 500;
            app.ApplyFIRFilterCheckBox = uicheckbox(app.FIRFilterPanel);
            app.ApplyFIRFilterCheckBox.Text = 'Apply FIR Filter';
            app.ApplyFIRFilterCheckBox.Value = true;
            app.AnalyzeButton = uibutton(app.FIRFilterPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.PlayFilteredAudioButton = uibutton(app.FIRFilterPanel, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.FilterOrder50Label = uilabel(app.FIRFilterPanel);
            app.FilterOrder50Label.Text = 'Filter Order: 50';
            app.WindowHammingLabel = uilabel(app.FIRFilterPanel);
            app.WindowHammingLabel.Text = 'Window = Hamming';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_under_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end